classdef ImportDialogApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImportDataUIFigure          matlab.ui.Figure
        DataPreviewLabel            matlab.ui.control.Label
        TabGroup                    matlab.ui.container.TabGroup
        WITecTab                    matlab.ui.container.Tab
        ImportdatadirectlyfromWITecsoftwareinthewipformatLabel  matlab.ui.control.Label
        SelectWipFilesButton        matlab.ui.control.Button
        MATLABExportTab             matlab.ui.container.Tab
        SelectMatFilesButton        matlab.ui.control.Button
        NoFolderSelectedLabel       matlab.ui.control.Label
        SelectFolderButton          matlab.ui.control.Button
        StartImportButton           matlab.ui.control.Button
        ImportdatadirectlyfromWITecsoftwareinthewipformatLabel_2  matlab.ui.control.Label
        ASCIITab                    matlab.ui.container.Tab
        ImportdatadirectlyfromWITecsoftwareinthewipformatLabel_3  matlab.ui.control.Label
        CancelButton                matlab.ui.control.Button
        AppendtocurrentlyloadeddatasetCheckBox  matlab.ui.control.CheckBox
        UIPreviewTable              matlab.ui.control.Table
        ImportOptionsPanel          matlab.ui.container.Panel
        KeepOriginalCheckBox        matlab.ui.control.CheckBox
        ConverttoRelativeWavenumbersCheckBox  matlab.ui.control.CheckBox
        BaselineCorrectionCheckBox  matlab.ui.control.CheckBox
        CosmicRayRemovalCheckBox    matlab.ui.control.CheckBox
        NormalizeCheckBox           matlab.ui.control.CheckBox
        ImportConfirmButton         matlab.ui.control.Button
        StatusTextArea              matlab.ui.control.TextArea
        StatusTextAreaLabel         matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp % Main App
        ImportedData
        SelectedFolder
        tmpPrj % Temporary Project() Object containing data
    end
    
    methods (Access = public)
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.CallingApp = mainApp;
            app.SelectedFolder = 0;
        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
            % Start in working directory
            start_path = pwd;
            thisFolder = uigetdir(start_path);
            
            if thisFolder == 0
                return;
            else
                app.SelectedFolder = thisFolder;
                app.StartImportButton.Enable = true;
                app.NoFolderSelectedLabel.Text = thisFolder;
            end
        end

        % Button pushed function: ImportConfirmButton
        function ImportConfirmButtonPushed(app, event)
            %IMPORTCONFIRMBUTTONPUSHED
            
            % Call method in main app to load in the imported data
            % app.ImportedData:     array of DataContainer instances
            loadImportedData(app.CallingApp, app.ImportedData);
            
            % Close the import dialog
            delete(app);
        end

        % Button pushed function: StartImportButton
        function StartImportButtonPushed(app, event)
            if (app.SelectedFolder ~= 0)
                app.ImportConfirmButton.Text = "Processing ...";
                
                % Start import
                data = importRaman(app.SelectedFolder, app);
                
                % Data has been imported
                % Create preview                
                previewTable = table(...
                    {true(numel(dat),1)},...
                    {data.display_name}', ...
                    {data.dataType}');
                app.UIPreviewTable.Data = previewTable;
                
                app.ImportedData = data;
                                
                app.ImportConfirmButton.Text = "Confirm";
                app.ImportConfirmButton.Enable = true;
                
            end
        end

        % Button pushed function: SelectWipFilesButton
        function SelectWipFilesButtonPushed(app, event)
            % SELECTWIPFILESBUTTONPUSHED Show file selection dialog for WIP
            % files
            
            % TO-DO: select last used folder

            % Get options
            opts_struct = get_processing_options(app);

            data = import_data([], type=0, start_path=pwd, processing=opts_struct, gui=app.StatusTextArea);

            if isempty(data)
                return;
            end
                
            % Data has been imported
            % Create preview
            % TO-DO: improve table() method in datacontainer
            previewTable = table(...
                true(numel(data),1),...
                {data.display_name}', ...
                {data.dataType}');
            app.UIPreviewTable.Data = previewTable;
            
            app.ImportedData = data;
                            
            app.ImportConfirmButton.Text = "Confirm";
            app.ImportConfirmButton.Enable = true;
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImportDataUIFigure = uifigure('Visible', 'off');
            app.ImportDataUIFigure.Name = 'Import Data';
            app.StatusTextAreaLabel = uilabel(app.ImportDataUIFigure);
            app.StatusTextAreaLabel.Text = 'Status';
            app.StatusTextArea = uitextarea(app.ImportDataUIFigure);
            app.ImportConfirmButton = uibutton(app.ImportDataUIFigure, 'push');
            app.ImportConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ImportConfirmButtonPushed, true);
            app.ImportConfirmButton.Enable = 'off';
            app.ImportConfirmButton.Text = 'Confirm';
            app.ImportOptionsPanel = uipanel(app.ImportDataUIFigure);
            app.ImportOptionsPanel.Title = 'Import Options';
            app.NormalizeCheckBox = uicheckbox(app.ImportOptionsPanel);
            app.NormalizeCheckBox.Text = 'Normalize';
            app.NormalizeCheckBox.Value = true;
            app.CosmicRayRemovalCheckBox = uicheckbox(app.ImportOptionsPanel);
            app.CosmicRayRemovalCheckBox.Enable = 'off';
            app.CosmicRayRemovalCheckBox.Text = 'Cosmic Ray Removal';
            app.BaselineCorrectionCheckBox = uicheckbox(app.ImportOptionsPanel);
            app.BaselineCorrectionCheckBox.Text = 'Baseline Correction';
            app.BaselineCorrectionCheckBox.Value = true;
            app.ConverttoRelativeWavenumbersCheckBox = uicheckbox(app.ImportOptionsPanel);
            app.ConverttoRelativeWavenumbersCheckBox.Text = 'Convert to Relative Wavenumbers in cm-1 (Raman Shift)';
            app.ConverttoRelativeWavenumbersCheckBox.Value = true;
            app.KeepOriginalCheckBox = uicheckbox(app.ImportOptionsPanel);
            app.KeepOriginalCheckBox.Tooltip = {'Keeps a copy of the original spectral data. Unchecking this option will perform spectral corrections and overwrite the spectral data.'};
            app.KeepOriginalCheckBox.Text = 'Keep Original';
            app.UIPreviewTable = uitable(app.ImportDataUIFigure);
            app.UIPreviewTable.ColumnName = {'Import'; 'Name'; 'Type'};
            app.UIPreviewTable.RowName = {};
            app.UIPreviewTable.ColumnEditable = [true true false];
            app.AppendtocurrentlyloadeddatasetCheckBox = uicheckbox(app.ImportDataUIFigure);
            app.AppendtocurrentlyloadeddatasetCheckBox.Text = 'Append to currently loaded data set';
            app.AppendtocurrentlyloadeddatasetCheckBox.Value = true;
            app.CancelButton = uibutton(app.ImportDataUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.TabGroup = uitabgroup(app.ImportDataUIFigure);
            app.WITecTab = uitab(app.TabGroup);
            app.WITecTab.Title = 'WITec';
            app.SelectWipFilesButton = uibutton(app.WITecTab, 'push');
            app.SelectWipFilesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectWipFilesButtonPushed, true);
            app.SelectWipFilesButton.Text = 'Select Files';
            app.ImportdatadirectlyfromWITecsoftwareinthewipformatLabel = uilabel(app.WITecTab);
            app.ImportdatadirectlyfromWITecsoftwareinthewipformatLabel.Text = {'Import data directly from WITec software (ProjectFOUR, ProjectFIVE, ...)'; 'in the .wip format.'};
            app.MATLABExportTab = uitab(app.TabGroup);
            app.MATLABExportTab.Title = 'MATLAB Export';
            app.ImportdatadirectlyfromWITecsoftwareinthewipformatLabel_2 = uilabel(app.MATLABExportTab);
            app.ImportdatadirectlyfromWITecsoftwareinthewipformatLabel_2.Text = {'Import data exported by the WITec ProjectFIVE software'; 'in MATLAB format.'};
            app.StartImportButton = uibutton(app.MATLABExportTab, 'push');
            app.StartImportButton.ButtonPushedFcn = createCallbackFcn(app, @StartImportButtonPushed, true);
            app.StartImportButton.Enable = 'off';
            app.StartImportButton.Text = 'Start Import';
            app.SelectFolderButton = uibutton(app.MATLABExportTab, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'Select Folder';
            app.NoFolderSelectedLabel = uilabel(app.MATLABExportTab);
            app.NoFolderSelectedLabel.Text = 'No Folder Selected...';
            app.SelectMatFilesButton = uibutton(app.MATLABExportTab, 'push');
            app.SelectMatFilesButton.Text = 'Select Files';
            app.ASCIITab = uitab(app.TabGroup);
            app.ASCIITab.Title = 'ASCII';
            app.ImportdatadirectlyfromWITecsoftwareinthewipformatLabel_3 = uilabel(app.ASCIITab);
            app.ImportdatadirectlyfromWITecsoftwareinthewipformatLabel_3.Text = 'Import ASCII data';
            app.DataPreviewLabel = uilabel(app.ImportDataUIFigure);
            app.DataPreviewLabel.Text = 'Data Preview';
            % Show the figure after all components are created
            app.ImportDataUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImportDialogApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImportDataUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImportDataUIFigure)
        end
    end
end